classdef Corona_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CoronaAppUIFigure            matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PlacePanel                   matlab.ui.container.Panel
        CountryLabel                 matlab.ui.control.Label
        CountryListBox               matlab.ui.control.ListBox
        StateorRegionLabel           matlab.ui.control.Label
        StateorRegionListBox         matlab.ui.control.ListBox
        SearchCountryEditFieldLabel  matlab.ui.control.Label
        SearchCountryEditField       matlab.ui.control.EditField
        SearchStateEditFieldLabel    matlab.ui.control.Label
        SearchStateEditField         matlab.ui.control.EditField
        OptionsPanel                 matlab.ui.container.Panel
        DatatoPlotButtonGroup        matlab.ui.container.ButtonGroup
        CasesButton                  matlab.ui.control.RadioButton
        DeathsButton                 matlab.ui.control.RadioButton
        BothButton                   matlab.ui.control.RadioButton
        OptionButtonGroup            matlab.ui.container.ButtonGroup
        CumulativeButton             matlab.ui.control.RadioButton
        DailyButton                  matlab.ui.control.RadioButton
        AveragedofDaysSliderLabel    matlab.ui.control.Label
        AveragedofDaysSlider         matlab.ui.control.Slider
        Thisdataisfrom21Jan2020to6Sep2020Label  matlab.ui.control.Label
    end


    properties (Access = private)
        c_arr       % Country array
        
        x_start
        x_end
        
        selected_c
        selected_s
    end

    methods (Access = private)
        % Function to calculate the remaining loops to store the data
        function pl = Extra(app, R)
            pl = 0;
            for k = 1:(length(R)-1)
                if isempty(R{k})
                    if ~isempty(R{k+1})
                        pl = pl + 1;
                    end
                end
            end
        end
        %******************************************%
        
        function ploting(app, n)
            %*Setting Default n*%
            if nargin < 2
                n = round(app.AveragedofDaysSlider.Value);      % used this method because my MATLAB is 2018a
            end
            t4 = "";
            if n > 1
                t4 = " (" + string(n) + "-day mean)";
            end
            %**************%
            
            %*Getting cases & deaths data*%
            index = Get_Index(app);
            state_selected = string(app.StateorRegionListBox.Value);
            if state_selected ~= "<All>"
                index(2) = find(state_selected == [app.c_arr(index).get_state_obj.state]);
            end
            
            if size(index, 2) > 1
                st_obj = app.c_arr(index(1)).get_state_obj;
                if app.CumulativeButton.Value == true                    
                    [To_Bar, To_Plot] = st_obj(index(2)).Avr_Casses_Deaths(n, "Cumulative");
                    t1 = "Cumulative";
                else
                    [To_Bar, To_Plot] = st_obj(index(2)).Avr_Casses_Deaths(n, "Daily");
                    t1 = "Daily";
                end
                t3 = st_obj(index(2)).state;
            else
                if app.CumulativeButton.Value == true
                    [To_Bar, To_Plot] = app.c_arr(index).Avr_Casses_Deaths(n, "Cumulative");
                    t1 = "Cumulative";
                else
                    [To_Bar, To_Plot] = app.c_arr(index).Avr_Casses_Deaths(n, "Daily");
                    t1 = "Daily";
                end
                t3 = app.c_arr(index).country;
            end
            %**************%
            
            %*Setting flags to the data to plot*%
            Bar = false;
            Plot = false;
            if app.CasesButton.Value == true
                Bar = true;               
            elseif app.DeathsButton.Value == true
                Plot = true;
            else
                Bar = true;
                Plot = true;
            end
            %**************%
            
            %*Plotting according to the flags*%
            cla(app.UIAxes, 'reset')
            if Bar && Plot
                yyaxis(app.UIAxes, 'left')
                bar(app.UIAxes, app.x_start + n - 1:app.x_end, To_Bar)
                hold(app.UIAxes, 'on')
                yyaxis(app.UIAxes, 'right')
                plot(app.UIAxes, app.x_start + n - 1:app.x_end, To_Plot, 'LineWidth', 2)
                app.UIAxes.YAxis(2).Exponent = (0);
                t2 = " Cases and Deaths ";
            elseif Bar
                bar(app.UIAxes, app.x_start + n - 1:app.x_end, To_Bar)
                t2 = " Cases ";
            elseif Plot
                plot(app.UIAxes, app.x_start + n - 1:app.x_end, To_Plot, 'r', 'LineWidth', 2)
                t2 = " Deaths ";
            end
            
            
            app.UIAxes.Title.String = t1 + " Number of " + t2 + "in " + t3 + t4 ;
            grid(app.UIAxes, 'on')
            app.UIAxes.YAxis(1).Exponent = (0);
            ytickformat(app.UIAxes, '%,.0f')
            xlim(app.UIAxes, [datenum(2020,1,1) app.x_end+4])
            datetick(app.UIAxes, 'x','mm/dd','keeplimits')
            hold(app.UIAxes, 'off')
            %**************%
        end
        %*****************************************%
        
        function index = Get_Index(app)
            index = find(string(app.CountryListBox.Value) == [app.c_arr.country]);
        end
        %*****************************************%
        
        function all = Create_All(app, covid_data)
            all = cell(1, size(covid_data, 2));
            all{1} = '<All>';
            for i = 3:size(covid_data, 2)
                a = [covid_data{2:end, i}];
                a1 = sum(a(1:2:end));
                a2 = sum(a(2:2:end));
                all{i} = [a1 a2];
            end
        end
        %****************************************%
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data and store it in (covid_data variable)
            load covid_data.mat covid_data;
            % Make copy of the state column in (r variable)
            r = covid_data(2:end, 2);
            % Calculate the remaining loops to store the data in (pls variable)
            pls = app.Extra(r);
            % Calculate numbers of the unempty cells in state column
            emp = length(r(~cellfun('isempty',r)));
            % Define the property type
            app.c_arr = Country.empty(emp, 0);
            % Set change variable to 0 at the beggining (this variable helps to calculate the covid_data indexing right)
            change = 0;
            
            % Set the data in property (c_arr) % Start
            for i = 2:(size(covid_data, 1) + pls - emp)
                if isempty(covid_data{i+change, 2})
                    app.c_arr(end+1) = Country(covid_data(i+change, :));
                else
                    st = find(app.c_arr(end).country == string(covid_data(:,1)));
                    s_arr = State.empty(length(st)-1, 0);
                    for j = 2:length(st)
                        s_arr(j-1) = State(covid_data(i+j+change-2, :));
                    end
                    set_state_obj(app.c_arr(end), s_arr);
                    change = change + size(s_arr, 2) - 1;
                end
            end
            all = Country(Create_All(app, covid_data));
            app.c_arr = [all app.c_arr];
            % Set the data in property (c_arr) % End
            
            app.x_start = datenum(covid_data{1, 3});
            app.x_end = datenum(covid_data{1, end});
            
            app.CountryListBox.Items = [app.c_arr.country];
            app.StateorRegionListBox.Items = ["<All>"];
            
            ploting(app)
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            index_c = Get_Index(app);
            states = app.c_arr(index_c).get_state_obj;
            app.StateorRegionListBox.Items = ["<All>" states.state];
            app.selected_c = app.c_arr(index_c).country;
            ploting(app)            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            index_c = Get_Index(app);
            states = app.c_arr(index_c).get_state_obj;
            state_selected = string(app.StateorRegionListBox.Value);
            if state_selected ~= "<All>"
                ii = state_selected == [app.c_arr(index_c).get_state_obj.state];            
                app.selected_s = states(ii).state;
            else
                app.selected_s = "<All>";
            end
            ploting(app)            
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            ploting(app)            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            ploting(app)            
        end

        % Value changing function: SearchCountryEditField
        function SearchCountryEditFieldValueChanging(app, event)
            if string(event.Value) ~= ""
                changingValue = char(event.Value);
                changingValue(1) = upper(changingValue(1));
                str = string(changingValue);
                ind = contains([app.c_arr.country], str);
                if sum(ind)
                    app.CountryListBox.Items = [app.selected_c app.c_arr(ind).country];
                else
                    app.CountryListBox.Items = [app.selected_c "<All>"];
                end
            else
                app.CountryListBox.Items = [app.c_arr.country];
            end
        end

        % Value changing function: SearchStateEditField
        function SearchStateEditFieldValueChanging(app, event)
            index_s = Get_Index(app);
            states = app.c_arr(index_s).get_state_obj; 
            if string(event.Value) ~= ""
                changingValue = char(event.Value);
                changingValue(1) = upper(changingValue(1));
                str = string(changingValue);
                ind = contains([states.state], str);
                if sum(ind)
                    app.StateorRegionListBox.Items = [states(ind).state];
                else
                    app.StateorRegionListBox.Items = [app.selected_s "<All>"];
                end
            else
                app.StateorRegionListBox.Items = ["<All>" states.state];
            end
            
        end

        % Value changed function: AveragedofDaysSlider
        function AveragedofDaysSliderValueChanged(app, event)
            used = round(app.AveragedofDaysSlider.Value);
            app.AveragedofDaysSlider.Value = used;
            ploting(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CoronaAppUIFigure = uifigure;
            app.CoronaAppUIFigure.Name = 'Corona App';
            app.UIAxes = uiaxes(app.CoronaAppUIFigure);
            title(app.UIAxes, 'Title')
            app.PlacePanel = uipanel(app.CoronaAppUIFigure);
            app.CountryLabel = uilabel(app.PlacePanel);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.PlacePanel);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.PlacePanel);
            app.StateorRegionLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.PlacePanel);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.SearchCountryEditFieldLabel = uilabel(app.PlacePanel);
            app.SearchCountryEditFieldLabel.Text = 'Search Country';
            app.SearchCountryEditField = uieditfield(app.PlacePanel, 'text');
            app.SearchCountryEditField.ValueChangingFcn = createCallbackFcn(app, @SearchCountryEditFieldValueChanging, true);
            app.SearchStateEditFieldLabel = uilabel(app.PlacePanel);
            app.SearchStateEditFieldLabel.Text = 'Search State';
            app.SearchStateEditField = uieditfield(app.PlacePanel, 'text');
            app.SearchStateEditField.ValueChangingFcn = createCallbackFcn(app, @SearchStateEditFieldValueChanging, true);
            app.OptionsPanel = uipanel(app.CoronaAppUIFigure);
            app.DatatoPlotButtonGroup = uibuttongroup(app.OptionsPanel);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.OptionsPanel);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofDaysSliderLabel = uilabel(app.OptionsPanel);
            app.AveragedofDaysSliderLabel.Text = 'Averaged # of Days';
            app.AveragedofDaysSlider = uislider(app.OptionsPanel);
            app.AveragedofDaysSlider.Limits = [1 15];
            app.AveragedofDaysSlider.MajorTicks = [1 3 5 7 9 11 13 15];
            app.AveragedofDaysSlider.ValueChangedFcn = createCallbackFcn(app, @AveragedofDaysSliderValueChanged, true);
            app.AveragedofDaysSlider.Value = 1;
            app.Thisdataisfrom21Jan2020to6Sep2020Label = uilabel(app.CoronaAppUIFigure);
            app.Thisdataisfrom21Jan2020to6Sep2020Label.Text = 'This data is from (21 Jan 2020) to (6 Sep 2020)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Corona_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CoronaAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CoronaAppUIFigure)
        end
    end
end